classdef BinaryThresholding_Page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageNameEditField         matlab.ui.control.EditField
        ImageNameEditFieldLabel    matlab.ui.control.Label
        SaveImageButton            matlab.ui.control.Button
        BinaryThresholdingLabel    matlab.ui.control.Label
        ThresholdValueSlider       matlab.ui.control.Slider
        ThresholdValueSliderLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Value changed function: ThresholdValueSlider
        function ThresholdValueSliderValueChanged(app, event)
            
        end

        % Value changed function: ImageNameEditField
        function ImageNameEditFieldValueChanged(app, event)
            value = app.ImageNameEditField.Value;
            img = imread(value);
            img = cat(3, img, img, img);
            app.Image.ImageSource = img;
        end

        % Value changing function: ThresholdValueSlider
        function ThresholdValueSliderValueChanging(app, event)
            changingValue = event.Value;

            img = imread(app.ImageNameEditField.Value);
            img = im2bw(img, changingValue/255);
            img = 255 * repmat(uint8(img), 1, 1, 3);

            app.Image.ImageSource = img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ThresholdValueSliderLabel = uilabel(app.UIFigure);
            app.ThresholdValueSliderLabel.Text = 'Threshold Value';
            app.ThresholdValueSlider = uislider(app.UIFigure);
            app.ThresholdValueSlider.Limits = [0 255];
            app.ThresholdValueSlider.MajorTicks = [0 50 100 150 200 255];
            app.ThresholdValueSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueSliderValueChanged, true);
            app.ThresholdValueSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdValueSliderValueChanging, true);
            app.BinaryThresholdingLabel = uilabel(app.UIFigure);
            app.BinaryThresholdingLabel.Text = 'Binary Thresholding';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.ImageNameEditFieldLabel = uilabel(app.UIFigure);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.ImageNameEditField = uieditfield(app.UIFigure, 'text');
            app.ImageNameEditField.ValueChangedFcn = createCallbackFcn(app, @ImageNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinaryThresholding_Page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end