classdef BoundingBox_Page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveImageButton           matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        BoundingBoxLabel          matlab.ui.control.Label
        NumObjectsEditField       matlab.ui.control.NumericEditField
        NumObjectsEditFieldLabel  matlab.ui.control.Label
        ImageNameEditField        matlab.ui.control.EditField
        ImageNameEditFieldLabel   matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumObjectsEditField
        function NumObjectsEditFieldValueChanged(app, event)
            numObj = app.NumObjectsEditField.Value;
            fileName = app.ImageNameEditField.Value;
            if(fileName ~= 0)
                
                img = imread(fileName);
                img = imbinarize(img); % Otsu Thresholding
                filtImg = bwareafilt(img, numObj); % grab bounding box for largest connected component
                bb = regionprops(filtImg, 'BoundingBox');
                f = figure('visible', 'off');
                imshow(img, 'border', 'tight');

                for i=1:numObj
                    rectangle('Position',[bb(i).BoundingBox(1,1), bb(i).BoundingBox(1,2),bb(i).BoundingBox(1,3),bb(i).BoundingBox(1,4)],'LineWidth',2,'EdgeColor','g');
                end

                frm = getframe(f);
                img = frm.cdata;
                close(f);
                app.Image.ImageSource = img;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.ImageNameEditFieldLabel = uilabel(app.UIFigure);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.ImageNameEditField = uieditfield(app.UIFigure, 'text');
            app.NumObjectsEditFieldLabel = uilabel(app.UIFigure);
            app.NumObjectsEditFieldLabel.Text = 'Num Objects';
            app.NumObjectsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumObjectsEditField.ValueChangedFcn = createCallbackFcn(app, @NumObjectsEditFieldValueChanged, true);
            app.BoundingBoxLabel = uilabel(app.UIFigure);
            app.BoundingBoxLabel.Text = 'Bounding Box';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'This program takes a grayscale image and a desired number of objects, n.  First, the image is thresholded using Otsu''s thresholding.  Next, the program finds bounding boxes for the n largest connected components (objects) in the thresholded image.  If the desired number of objects exceeds the total number of connected components in the image, an error message will occur in the terminal, and the output image will have the maximum number of bounding boxes (one for each object)';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.Text = 'Save Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundingBox_Page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end