classdef PO_Page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GoButton                  matlab.ui.control.Button
        OperationsButtonGroup     matlab.ui.container.ButtonGroup
        DivisionButton            matlab.ui.control.RadioButton
        MultiplicationButton      matlab.ui.control.RadioButton
        SubtractionButton         matlab.ui.control.RadioButton
        AdditionButton            matlab.ui.control.RadioButton
        NearestNeighborResamplingisamethodLabel  matlab.ui.control.Label
        PointwiseOperationsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if(app.AdditionButton.Value == 1)
                A_Page
                delete(app);
            end 
            if(app.SubtractionButton.Value == 1)
                S_Page
                delete(app);
            end 
            if(app.MultiplicationButton.Value == 1)
                M_Page
                delete(app);
            end 
            if(app.DivisionButton.Value == 1)
                D_Page
                delete(app);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PointwiseOperationsLabel = uilabel(app.UIFigure);
            app.PointwiseOperationsLabel.Text = 'Pointwise Operations';
            app.NearestNeighborResamplingisamethodLabel = uilabel(app.UIFigure);
            app.NearestNeighborResamplingisamethodLabel.Text = 'Pointwise operations is application of normal math functions onto 2 images. Such as for Pointwise subtraction, pixel (1,1) in the first image is subtracted by pixel (1,1) in the second image. These operations are usually used to find values that will be used in other operations, such as image averageing, darkfield, and linear response.';
            app.OperationsButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationsButtonGroup.Title = 'Operations';
            app.AdditionButton = uiradiobutton(app.OperationsButtonGroup);
            app.AdditionButton.Text = 'Addition';
            app.AdditionButton.Value = true;
            app.SubtractionButton = uiradiobutton(app.OperationsButtonGroup);
            app.SubtractionButton.Text = 'Subtraction';
            app.MultiplicationButton = uiradiobutton(app.OperationsButtonGroup);
            app.MultiplicationButton.Text = 'Multiplication';
            app.DivisionButton = uiradiobutton(app.OperationsButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PO_Page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end