classdef SingleImage_Page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SingleImageOperationButtonGroup  matlab.ui.container.ButtonGroup
        BoundingBoxButton             matlab.ui.control.RadioButton
        BinaryThresholdingButton      matlab.ui.control.RadioButton
        BrightnessContrastRemappingButton  matlab.ui.control.RadioButton
        HistogramSpecificationButton  matlab.ui.control.RadioButton
        NNRButton                     matlab.ui.control.RadioButton
        GoButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if(app.NNRButton.Value == 1)
                NNR_Page
            end
            if(app.HistogramSpecificationButton.Value == 1)
                HistogramSpecification_Page
            end
            if(app.BrightnessContrastRemappingButton.Value == 1)
                BrightCon_Page
            end
            if(app.BinaryThresholdingButton.Value == 1)
                BinaryThresholding_Page
            end
            if(app.BoundingBoxButton.Value == 1)
                BoundingBox_Page
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.SingleImageOperationButtonGroup = uibuttongroup(app.UIFigure);
            app.SingleImageOperationButtonGroup.Title = 'Single Image Operation';
            app.NNRButton = uiradiobutton(app.SingleImageOperationButtonGroup);
            app.NNRButton.Tag = 'thing';
            app.NNRButton.Text = 'NNR';
            app.NNRButton.Value = true;
            app.HistogramSpecificationButton = uiradiobutton(app.SingleImageOperationButtonGroup);
            app.HistogramSpecificationButton.Text = 'Histogram Specification';
            app.BrightnessContrastRemappingButton = uiradiobutton(app.SingleImageOperationButtonGroup);
            app.BrightnessContrastRemappingButton.Text = 'Brightness/Contrast Remapping';
            app.BinaryThresholdingButton = uiradiobutton(app.SingleImageOperationButtonGroup);
            app.BinaryThresholdingButton.Text = 'Binary Thresholding';
            app.BoundingBoxButton = uiradiobutton(app.SingleImageOperationButtonGroup);
            app.BoundingBoxButton.Text = 'Bounding Box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleImage_Page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end